classdef PLAYER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ClearPlotButton          matlab.ui.control.Button
        AUDIOSIGNALPLOTTERLabel  matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        fileusedLabel            matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        UIAxesSpec               matlab.ui.control.UIAxes
        UIAxesFFT                matlab.ui.control.UIAxes
        UIAxesRC                 matlab.ui.control.UIAxes
        UIAxesLC                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y 
        Fs
        fullFilePath
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files'}, 'Select an audio file');

            if isequal(file,0)
                uialert(app.UIFigure, 'No file selected', 'Warning');
                return ;
            end
             %change label name 
             app.fileusedLabel.Text = ['Loaded: ' file];

             % Store full path
             app.fullFilePath = fullfile(path, file);

             % Read the audio file
             [app.y, app.Fs] = audioread(app.fullFilePath);   

             %indicator 
             disp("loading succesful");
               
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            a = audioplayer(app.y , app.Fs) ;
            play(a);
            sound(app.y ,app.Fs);
            disp("playing successful");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            S = size(app.y);
            disp("numbers of samples and channels =") ;
            disp(S);
            t = (0:length(app.y)-1)/app.Fs;        % Time vector
            plot(app.UIAxesLC ,t, app.y(:,1));     %left channel plot
            if size(app.y, 2) >= 2
                plot(app.UIAxesRC, t, app.y(:,2)); %right channel plot 
            end 

            Y = fft(app.y);                        %FFT plot
            L = length(app.y);
            P2 = abs(Y/L) ;
            P1 = P2(1:floor(L/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = app.Fs*(0:(L/2))/L;
            plot(app.UIAxesFFT,f, P1);

            disp("Plotting successful");
            cla(app.UIAxesSpec);
            spectrogram(app.y(:,1), 256, 250, 256, app.Fs, 'yaxis', 'Parent', app.UIAxesSpec);

        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxesRC) ;                 %button to clear all plots
            cla(app.UIAxesLC) ;
            cla(app.UIAxesFFT) ;
            cla(app.UIAxesSpec);
            disp("Cleared graphs successfully");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLC = uiaxes(app.UIFigure);
            title(app.UIAxesLC, 'Left Channel')
            xlabel(app.UIAxesLC, 'Time(t)')
            ylabel(app.UIAxesLC, 'Amplitude')
            zlabel(app.UIAxesLC, 'Z')
            app.UIAxesRC = uiaxes(app.UIFigure);
            title(app.UIAxesRC, 'Right Channel')
            xlabel(app.UIAxesRC, 'Time(t)')
            ylabel(app.UIAxesRC, 'Amplitude')
            zlabel(app.UIAxesRC, 'Z')
            app.UIAxesFFT = uiaxes(app.UIFigure);
            title(app.UIAxesFFT, 'Amplitude vs Frequency')
            xlabel(app.UIAxesFFT, 'Frequency(Hz)')
            ylabel(app.UIAxesFFT, 'Amplitude')
            zlabel(app.UIAxesFFT, 'Z')
            app.UIAxesSpec = uiaxes(app.UIFigure);
            title(app.UIAxesSpec, 'Spectrogram')
            xlabel(app.UIAxesSpec, 'X')
            ylabel(app.UIAxesSpec, 'Y')
            zlabel(app.UIAxesSpec, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'up-loading.png');
            app.LoadButton.Text = 'Load';
            app.fileusedLabel = uilabel(app.UIFigure);
            app.fileusedLabel.Text = '--file used--';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'play-button.png');
            app.PlayButton.Text = 'Play';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'cosine.png');
            app.PlotButton.Text = 'Plot';
            app.AUDIOSIGNALPLOTTERLabel = uilabel(app.UIFigure);
            app.AUDIOSIGNALPLOTTERLabel.Text = 'AUDIO SIGNAL PLOTTER';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PLAYER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end